classdef OWL_UtilitiesGuideApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OWLUtilitiesGuideUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        ReferencePanel             matlab.ui.container.Panel
        CopyCommentNotesButton     matlab.ui.control.Button
        CopyDisplayNotesButton     matlab.ui.control.Button
        NotesTextArea              matlab.ui.control.TextArea
        NotesTextAreaLabel         matlab.ui.control.Label
        ExampleTextArea            matlab.ui.control.TextArea
        ExampleTextAreaLabel       matlab.ui.control.Label
        InputsTextArea             matlab.ui.control.TextArea
        InputsTextAreaLabel        matlab.ui.control.Label
        SignatureTextArea          matlab.ui.control.TextArea
        SignatureTextAreaLabel     matlab.ui.control.Label
        PurposeTextArea            matlab.ui.control.TextArea
        PurposeTextAreaLabel       matlab.ui.control.Label
        RefreshButton              matlab.ui.control.Button
        OpenHelpButton             matlab.ui.control.Button
        CopySignatureButton        matlab.ui.control.Button
        CopyExampleButton          matlab.ui.control.Button
        UtilitiesPanel             matlab.ui.container.Panel
        UtilitiesListBox           matlab.ui.control.ListBox
        SelectaUtilitiyLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        UtilitiesDB struct
    end
    
    methods (Access = private)
        
        function buildUtilitiesDB(app)

            db = struct;
            notes = [
                "Operates on the current axes (gca)."
                "Calling xlabel, ylabel, or title before this function is unnecessary."
                "Passing 'Default' for FontSizes uses {20, 20, 25}."
                "Grid and minor grid are always enabled."
                ];

            db.Plot_Format = struct( ...
                ...
                'NotesList', notes, ...
                'Notes', join(notes, newline)...
                );

            defaultEntry = struct( ...
                'Name',       "", ...
                'Purpose',    "", ...
                'Signature',  "", ...
                'Inputs',     "", ...
                'Example',    "", ...
                'Notes',      "", ...
                'NotesList',  strings(0,1), ...
                'HelpTopic',  "" ...
                );

            % ========================================================================
            % Plot_Format
            % ========================================================================
            db.Plot_Format = struct( ...
                'Name', 'Plot_Format', ...
                'Purpose', join([ ...
                "Applies a consistent visual standard to the current axes (gca)."
                "This function centralises axis formatting, font sizes, LaTeX"
                "interpreters, grid styling, and line widths to ensure uniform"
                "figure presentation across projects."
                ], newline), ...
                'Signature', ...
                "Plot_Format(X_Label_String, Y_Label_String, Title_String, FontSizes, Axis_LineWidth)", ...
                'Inputs', join([ ...
                "X_Label_String : X-axis label (string, LaTeX allowed)"
                "Y_Label_String : Y-axis label (string, LaTeX allowed)"
                "Title_String   : Title string (LaTeX allowed)"
                "FontSizes      : Cell {XFont, YFont, TitleFont} or 'Default'"
                "Axis_LineWidth : Scalar axis line width"
                ], newline), ...
                'Example', join([ ...
                "fig = figure;"
                "plot(x, y, 'LineWidth', 1.3);"
                ""
                "Plot_Format('$t$', '$y$', 'Time response', 'Default', 1.2);"
                ], newline), ...
                'Notes', join([ ...
                "Operates on the current axes (gca)."
                "Calling xlabel, ylabel, or title before this function is unnecessary."
                "Passing 'Default' for FontSizes uses {20, 20, 25}."
                "Grid and minor grid are always enabled."
                ], newline), ...
                'HelpTopic', 'Plot_Format' ...
                );

            % ========================================================================
            % Plot_Saver
            % ========================================================================
            db.Plot_Saver = struct( ...
                'Name', 'Plot_Saver', ...
                'Purpose', join([ ...
                "Conditionally saves a figure to disk using a consistent directory"
                "and file format. Saving behaviour is controlled explicitly by"
                "a boolean flag to support loop-based plotting workflows."
                ], newline), ...
                'Signature', ...
                "Plot_Saver(Current_Figure, File_Name, Save_Flag)", ...
                'Inputs', join([ ...
                "Current_Figure : Figure handle to save"
                "File_Name      : Base filename without extension"
                "Save_Flag      : Logical flag controlling whether saving occurs"
                ], newline), ...
                'Example', join([ ...
                "fig = gcf;"
                "Save_Flag = true;"
                "Plot_Saver(fig, 'response_plot', Save_Flag);"
                ], newline), ...
                'Notes', join([ ...
                "Figures are saved as PNG files."
                "A 'Figures' directory is created automatically if missing."
                "If Save_Flag is false, no file is written."
                ], newline), ...
                'HelpTopic', 'Plot_Saver' ...
                );

            % ========================================================================
            % Legend_Format
            % ========================================================================
            db.Legend_Format = struct( ...
                'Name', 'Legend_Format', ...
                'Purpose', join([ ...
                "Creates and formats a LaTeX-rendered legend with intelligent"
                "auto-placement based on data density, minimising overlap with"
                "plotted data."
                ], newline), ...
                'Signature', ...
                "Legend_Handle = Legend_Format(Legend_Entries, FontSize, Orientation, NumColumns, NumRows, AutoLocation, BoxOption, Padding)", ...
                'Inputs', join([ ...
                "Legend_Entries : Cell array or string of legend entries"
                "FontSize       : Legend font size (default: 20)"
                "Orientation    : 'vertical' or 'horizontal'"
                "NumColumns     : Number of legend columns"
                "NumRows        : Number of legend rows"
                "AutoLocation   : Logical flag for data-driven placement"
                "BoxOption      : 'on' or 'off'"
                "Padding        : Padding factor for density estimation"
                ], newline), ...
                'Example', join([ ...
                "plot(x, y1); hold on;"
                "plot(x, y2); hold off;"
                ""
                "Legend_Format({'Case A','Case B'}, 18, 'vertical', 1, 2, true);"
                ], newline), ...
                'Notes', join([ ...
                "Operates on the current axes (gca)."
                "Auto-location evaluates multiple candidate positions."
                "Returns the legend handle for further customisation if needed."
                ], newline), ...
                'HelpTopic', 'Legend_Format' ...
                );

            % ========================================================================
            % estimate_data_density (internal helper)
            % ========================================================================
            db.estimate_data_density = struct( ...
                'Name', 'estimate_data_density', ...
                'Purpose', join([ ...
                "Internal helper function used by Legend_Format to estimate"
                "data density beneath a candidate legend position."
                ], newline), ...
                'Signature', ...
                "Density = estimate_data_density(Ax, Legend_Pos, Padding)", ...
                'Inputs', join([ ...
                "Ax         : Axes handle"
                "Legend_Pos : Normalised legend position vector"
                "Padding    : Padding factor around legend bounds"
                ], newline), ...
                'Example', join([ ...
                "% This function is not intended to be called directly by users."
                ], newline), ...
                'Notes', join([ ...
                "Downsamples line data above 200 points for efficiency."
                "Used exclusively by Legend_Format."
                ], newline), ...
                'HelpTopic', 'estimate_data_density' ...
                );

            % ========================================================================
            % AutoPlot
            % ========================================================================
            db.AutoPlot = struct( ...
                'Name', 'AutoPlot', ...
                'Purpose', join([ ...
                "High-level plotting orchestrator for structured datasets."
                "Automatically extracts X/Y data from nested structs, applies"
                "standard formatting, builds tiled layouts, generates legends,"
                "and conditionally saves figures."
                ], newline), ...
                'Signature', ...
                "Output = AutoPlot(DataStruct, FieldChain, Tile_Rows, Tile_Cols, File_BaseName, Resolution_Preset)", ...
                'Inputs', join([ ...
                "DataStruct        : Nested struct containing plotting data"
                "FieldChain        : Cell array specifying path to target data"
                "Tile_Rows         : Number of tile rows"
                "Tile_Cols         : Number of tile columns"
                "File_BaseName     : Base filename for saved figures"
                "Resolution_Preset : Resolution identifier string"
                ], newline), ...
                'Example', join([ ...
                "Output = AutoPlot(Data, {'Results','Sensor'}, 2, 2, 'sensor_plots', 'High');"
                ], newline), ...
                'Notes', join([ ...
                "Assumes each plotted element contains fields 'X' and 'Y'."
                "Legend is applied only to the final tile."
                "Figure is saved only on the final iteration."
                "Returns handles for figures, axes, lines, and legends."
                ], newline), ...
                'HelpTopic', 'AutoPlot' ...
                );

            db.NotesComments = [

            "% ========================================================="
            "% OWL FRAMEWORK NOTES"
            "% ========================================================="
            "%"

            "% FRAMEWORK OVERVIEW"
            "% - Shared plotting and export utilities enforce consistent, publication-quality figures."
            "% - Numerical scripts focus on computation; formatting and export are delegated to utilities."
            "%"

            "% GLOBAL CONVENTIONS"
            "% - LaTeX interpreters are enforced for labels, ticks, titles, and legends."
            "% - Formatting functions operate on the current axes (gca) unless stated otherwise."
            "% - Visual consistency is enforced programmatically, not manually."
            "% - Utilities are reusable and not tied to a specific numerical method."
            "%"

            "% PLOT FORMATTING — Plot_Format"
            "% - Centralises axis and figure formatting."
            "% - Applies consistent font sizes, line widths, and grid styles."
            "% - Enables major and minor grids by default."
            "% - Forces LaTeX interpretation for all labels and titles."
            "% - Syntax:"
            "%   plot(x, y, 'LineWidth', 1.3);"
            "%   Plot_Format('$t$', '$y$', 'Time response', 'Default', 1.2);"
            "%"

            "% LEGEND HANDLING — Legend_Format"
            "% - Legend placement is data-driven using density estimation."
            "% - Multiple candidate positions are evaluated automatically."
            "% - Supports vertical and horizontal layouts."
            "% - Legend entries are rendered using LaTeX."
            "% - Syntax:"
            "%   Legend_Format({'Case A','Case B'}, 18, 'vertical', 1, 2, true);"
            "%"

            "% FIGURE SAVING — Plot_Saver"
            "% - Figure saving is explicitly controlled via a logical save flag."
            "% - Figures are saved to a dedicated Figures/ directory."
            "% - File formats and extensions are handled internally."
            "% - Syntax:"
            "%   Plot_Saver(gcf, 'response_plot', true);"
            "%"

            "% HIGH-LEVEL PLOTTING — AutoPlot"
            "% - Assumes structured data with explicit X and Y fields."
            "% - Uses field-chain access to avoid hard-coded paths."
            "% - Applies legends selectively to avoid clutter."
            "% - Returns figure, axes, line, and legend handles."
            "% - Syntax:"
            "%   AutoPlot(Data, {'Results','Sensor'}, 2, 2, 'sensor_plots', 'High');"
            "%"

            "% DESIGN PHILOSOPHY"
            "% - Formatting is enforced by utilities, not user discipline."
            "% - Visual consistency is a first-class concern."
            "% - Scripts remain concise and computation-focused."
            "% - Reproducibility and minimal boilerplate are prioritised."
            "clc;"
            "Function Read Me"
            "display_function_instructions('Plot_Format');"
            ];

            db.NotesDisplay = join([

            "OWL FRAMEWORK OVERVIEW"
            ""
            "This framework provides shared plotting and export utilities designed to enforce consistent, publication-quality figures."
            "Numerical scripts are expected to focus on computation, while formatting, legend handling, and export are delegated to utilities."
            ""

            "GLOBAL CONVENTIONS"
            ""
            "All plotting utilities enforce LaTeX interpreters for labels, ticks, titles, and legends."
            "Formatting functions operate on the current axes (gca) unless explicitly stated otherwise."
            "Visual consistency is enforced programmatically rather than manually."
            "Utilities are reusable across projects and are not tied to a specific numerical method."
            ""

            "PLOT FORMATTING — Plot_Format"
            ""
            "Purpose:"
            "Centralises all axis and figure formatting to avoid repeated boilerplate."
            ""
            "Typical usage:"
            "plot(x, y, 'LineWidth', 1.3);"
            "Plot_Format('$t$', '$y$', 'Time response', 'Default', 1.2);"
            ""

            "LEGEND HANDLING — Legend_Format"
            ""
            "Purpose:"
            "Automatically places and formats legends to minimise overlap with plotted data."
            ""
            "Typical usage:"
            "Legend_Format({'Case A','Case B'}, 18, 'vertical', 1, 2, true);"
            ""

            "FIGURE SAVING — Plot_Saver"
            ""
            "Purpose:"
            "Explicitly controls figure export to support loop-based workflows."
            ""
            "Typical usage:"
            "Plot_Saver(gcf, 'response_plot', true);"
            ""

            "HIGH-LEVEL PLOTTING — AutoPlot"
            ""
            "Purpose:"
            "Orchestrates structured plotting with automatic formatting, legend handling, and export."
            ""
            "Typical usage:"
            "AutoPlot(Data, {'Results','Sensor'}, 2, 2, 'sensor_plots', 'High');"
            ""

            "DESIGN PHILOSOPHY"
            ""
            "Formatting is enforced by utilities rather than user discipline."
            "Visual consistency is treated as a first-class concern."
            "Scripts remain concise, readable, and focused on numerical logic."

            ], newline);
            
            entry = defaultEntry;
            entry.Name  = "Framework_Notes";
            entry.Purpose = "Framework-wide conventions, assumptions, and usage guidance.";
            entry.Signature = "Documentation only";
            entry.Inputs = "N/A";
            entry.Example = "Use the Copy buttons to export notes.";
            entry.Notes = db.NotesDisplay;     % shown in UI
            entry.NotesList = db.NotesComments; % used for export
            entry.HelpTopic = "";

            db.Framework_Notes = entry;


            app.UtilitiesDB = db;

        end

        function txt = generateAllNotesCommentBlock(app)

            keys = fieldnames(app.UtilitiesDB);
            lines = strings(0);

            lines(end+1) = "% =========================================================";
            lines(end+1) = "% OWL UTILITY NOTES — CONSOLIDATED";
            lines(end+1) = "% =========================================================";
            lines(end+1) = "%";

            for k = 1:numel(keys)
                key = keys{k};
                item = app.UtilitiesDB.(key);

                if ~isfield(item, "NotesList")
                    continue
                end

                lines(end+1) = "% ---------------------------------------------------------";
                lines(end+1) = "% " + upper(key);
                lines(end+1) = "% ---------------------------------------------------------";

                notes = item.NotesList;
                for n = 1:numel(notes)
                    lines(end+1) = "% - " + notes(n);
                end

                lines(end+1) = "%";
            end

            txt = join(lines, newline);

        end        
        
        function populateList(app)

            allKeys = fieldnames(app.UtilitiesDB);
            validKeys = strings(0);

            for k = 1:numel(allKeys)
                value = app.UtilitiesDB.(allKeys{k});
                if isstruct(value)
                    validKeys(end+1,1) = allKeys{k};
                end
            end

            % Optional: force Framework_Notes to appear first
            if ismember("Framework_Notes", validKeys)
                validKeys = ["Framework_Notes"; setdiff(validKeys, "Framework_Notes", 'stable')];
            end

            app.UtilitiesListBox.Items = validKeys;

            if ~isempty(validKeys)
                app.UtilitiesListBox.Value = validKeys(1);
            end

        end


        function renderSelection(app)
            key = app.UtilitiesListBox.Value;
            if isempty(key) || ~isfield(app.UtilitiesDB, key)
                return
            end
            item = app.UtilitiesDB.(key);
            app.PurposeTextArea.Value   = item.Purpose;
            app.SignatureTextArea.Value = item.Signature;
            app.InputsTextArea.Value    = item.Inputs;
            app.ExampleTextArea.Value   = item.Example;
            app.NotesTextArea.Value     = item.Notes;
            app.CopyDisplayNotesButton.Enable = ~isempty(item.Notes);
            app.CopyCommentNotesButton.Enable = ~isempty(item.NotesList);            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.buildUtilitiesDB();
            app.populateList();
            app.renderSelection();
        end

        % Value changed function: UtilitiesListBox
        function UtilitiesListBoxValueChanged(app, event)
            app.renderSelection();
        end

        % Button pushed function: CopyExampleButton
        function CopyExampleButtonPushed(app, event)
            clipboard('copy', app.ExampleTextArea.Value);
        end

        % Button pushed function: CopySignatureButton
        function CopySignatureButtonPushed(app, event)
            clipboard('copy', app.SignatureTextArea.Value);
        end

        % Button pushed function: OpenHelpButton
        function OpenHelpButtonPushed(app, event)
            help(app.UtilitiesListBox.Value);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.buildUtilitiesDB();
            app.populateList();
            app.renderSelection();
        end

        % Button pushed function: CopyDisplayNotesButton
        function CopyDisplayNotesButtonPushed(app, event)
            key = app.UtilitiesListBox.Value;

            if isempty(key)
                return
            end

            item = app.UtilitiesDB.(key);

            if isempty(item.Notes)
                return
            end

            clipboard('copy', item.Notes);
        end

        % Button pushed function: CopyCommentNotesButton
        function CopyCommentNotesButtonPushed(app, event)
            key = app.UtilitiesListBox.Value;

            if isempty(key)
                return
            end

            item = app.UtilitiesDB.(key);

            if isempty(item.NotesList)
                return
            end

            clipboard('copy', join(item.NotesList, newline));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OWLUtilitiesGuideUIFigure = uifigure('Visible', 'off');
            app.OWLUtilitiesGuideUIFigure.Name = 'OWL Utilities Guide';
            app.GridLayout = uigridlayout(app.OWLUtilitiesGuideUIFigure);
            app.UtilitiesPanel = uipanel(app.GridLayout);
            app.UtilitiesPanel.Title = 'Utilities';
            app.SelectaUtilitiyLabel = uilabel(app.UtilitiesPanel);
            app.SelectaUtilitiyLabel.Text = 'Select a Utilitiy';
            app.UtilitiesListBox = uilistbox(app.UtilitiesPanel);
            app.UtilitiesListBox.Items = {'Plot_Formatter', 'Plot_Saver', 'Plot_Format_And_Save', 'Legend_Format', 'Compile', 'AutoPlot', 'Estimate_Data_Density', 'NotesList'};
            app.UtilitiesListBox.ValueChangedFcn = createCallbackFcn(app, @UtilitiesListBoxValueChanged, true);
            app.UtilitiesListBox.Value = 'Plot_Formatter';
            app.ReferencePanel = uipanel(app.GridLayout);
            app.ReferencePanel.Title = 'Reference';
            app.CopyExampleButton = uibutton(app.ReferencePanel, 'push');
            app.CopyExampleButton.ButtonPushedFcn = createCallbackFcn(app, @CopyExampleButtonPushed, true);
            app.CopyExampleButton.Text = 'Copy Example';
            app.CopySignatureButton = uibutton(app.ReferencePanel, 'push');
            app.CopySignatureButton.ButtonPushedFcn = createCallbackFcn(app, @CopySignatureButtonPushed, true);
            app.CopySignatureButton.Text = 'Copy Signature';
            app.OpenHelpButton = uibutton(app.ReferencePanel, 'push');
            app.OpenHelpButton.ButtonPushedFcn = createCallbackFcn(app, @OpenHelpButtonPushed, true);
            app.OpenHelpButton.Text = 'Open Help';
            app.RefreshButton = uibutton(app.ReferencePanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.PurposeTextAreaLabel = uilabel(app.ReferencePanel);
            app.PurposeTextAreaLabel.Text = 'PurposeTextArea';
            app.PurposeTextArea = uitextarea(app.ReferencePanel);
            app.SignatureTextAreaLabel = uilabel(app.ReferencePanel);
            app.SignatureTextAreaLabel.Text = 'SignatureTextArea';
            app.SignatureTextArea = uitextarea(app.ReferencePanel);
            app.InputsTextAreaLabel = uilabel(app.ReferencePanel);
            app.InputsTextAreaLabel.Text = 'InputsTextArea';
            app.InputsTextArea = uitextarea(app.ReferencePanel);
            app.ExampleTextAreaLabel = uilabel(app.ReferencePanel);
            app.ExampleTextAreaLabel.Text = 'ExampleTextArea';
            app.ExampleTextArea = uitextarea(app.ReferencePanel);
            app.NotesTextAreaLabel = uilabel(app.ReferencePanel);
            app.NotesTextAreaLabel.Text = 'NotesTextArea';
            app.NotesTextArea = uitextarea(app.ReferencePanel);
            app.CopyDisplayNotesButton = uibutton(app.ReferencePanel, 'push');
            app.CopyDisplayNotesButton.ButtonPushedFcn = createCallbackFcn(app, @CopyDisplayNotesButtonPushed, true);
            app.CopyDisplayNotesButton.Text = 'Copy Display Notes';
            app.CopyCommentNotesButton = uibutton(app.ReferencePanel, 'push');
            app.CopyCommentNotesButton.ButtonPushedFcn = createCallbackFcn(app, @CopyCommentNotesButtonPushed, true);
            app.CopyCommentNotesButton.Text = 'Copy Comment Notes';
            % Show the figure after all components are created
            app.OWLUtilitiesGuideUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OWL_UtilitiesGuideApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OWLUtilitiesGuideUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OWLUtilitiesGuideUIFigure)
        end
    end
end